classdef mainApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        PorousLabPanel        matlab.ui.container.Panel
        CreatemodelButton     matlab.ui.control.Button
        Image                 matlab.ui.control.Image
        PhysicsDropDown       matlab.ui.control.DropDown
        PhysicsDropDownLabel  matlab.ui.control.Label
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            close all; clc;

            % Path to source directory
            src_dir = fullfile(fileparts(mfilename('fullpath')), '..', 'src');
            addpath(genpath(src_dir));
            
            print_header;

        end

        % Button pushed function: CreatemodelButton
        function CreatemodelButtonPushed(app, event)
            
            if strcmp(app.PhysicsDropDown.Value,'M')
                mdl = Model_M();
            elseif strcmp(app.PhysicsDropDown.Value,'H')
                mdl = Model_H();
            elseif strcmp(app.PhysicsDropDown.Value,'HM')
                mdl = Model_HM();
            elseif strcmp(app.PhysicsDropDown.Value,'H2')
                mdl = Model_H2();
            end
            meshOptions(mdl);
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PorousLabPanel = uipanel(app.UIFigure);
            app.PorousLabPanel.Title = 'PorousLab';
            app.PhysicsDropDownLabel = uilabel(app.PorousLabPanel);
            app.PhysicsDropDownLabel.Text = 'Physics';
            app.PhysicsDropDown = uidropdown(app.PorousLabPanel);
            app.PhysicsDropDown.Items = {'M', 'H', 'H2', 'HM', 'H2M'};
            app.PhysicsDropDown.Value = 'M';
            app.Image = uiimage(app.PorousLabPanel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'logo_porouslab_vertical.png');
            app.CreatemodelButton = uibutton(app.PorousLabPanel, 'push');
            app.CreatemodelButton.ButtonPushedFcn = createCallbackFcn(app, @CreatemodelButtonPushed, true);
            app.CreatemodelButton.Text = 'Create model';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mainApp

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end